classdef cauchy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        StartButton           matlab.ui.control.Button
        TpusButtonGroup       matlab.ui.container.ButtonGroup
        Konvolucio            matlab.ui.control.RadioButton
        Szimulacio            matlab.ui.control.RadioButton
        N                     matlab.ui.control.Spinner
        DarabszmSpinnerLabel  matlab.ui.control.Label
        Picture               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function Futtatas(app, event)
            return;
        end

        % Button pushed function: StartButton
        function Start(app, event)
            try
                if app.Szimulacio.Value
                    n= app.N.Value;
                    if n<=0
                       return;
                    end
                    dx=0.1;
                    a=(trnd(1,n,1)+trnd(1,n,1))/2;
                    x=-10:dx:10;
                    histogram(app.Picture,a,x,'normalization','pdf','EdgeAlpha',0.0);
                    hold(app.Picture,'on');
                    plot(app.Picture,x,tpdf(x,1),'r','LineWidth',3);
                    hold(app.Picture,'off');
                    legend(app.Picture,'Szimulált','Elméleti');
                end
                if app.Konvolucio.Value
                    dx=0.001;
                    Epsilon=0.01;
                    x=-50:dx:50;
                    f=@(x) (1/pi)*1./(1+x.^2);
                    z=f(x);
                    y=conv(z,z);
                    a=2*min(x);b=2*max(x);
                    x=linspace(a,b,size(y,2));
                    dx=(b-a)/size(y,2);
                    y=y*dx;
                    x=x/2;
                    y=y*2; %hogy az integrál ne változzon
                    d=y<Epsilon; %Ezek nélkül túl hegyes lenne
                    x(d)=[];
                    y(d)=[];
                    plot(app.Picture,x,y,'g','LineWidth',3);
                    hold(app.Picture,'on');
                    plot(app.Picture,x,f(x),'r','LineWidth',3);
                    hold(app.Picture,'off');
                    legend(app.Picture,'Számolt','Elméleti');
                end
            catch ME
                msgbox(ME.message);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Picture = uiaxes(app.UIFigure);
            title(app.Picture, 'Sűrűségfüggvény')
            xlabel(app.Picture, 'X')
            ylabel(app.Picture, 'Y')
            zlabel(app.Picture, 'Z')
            app.DarabszmSpinnerLabel = uilabel(app.UIFigure);
            app.DarabszmSpinnerLabel.Text = 'Darabszám';
            app.N = uispinner(app.UIFigure);
            app.N.Limits = [1 1000000];
            app.N.RoundFractionalValues = 'on';
            app.N.ValueDisplayFormat = '%11.0d';
            app.N.Value = 20000;
            app.TpusButtonGroup = uibuttongroup(app.UIFigure);
            app.TpusButtonGroup.Title = 'Típus';
            app.Szimulacio = uiradiobutton(app.TpusButtonGroup);
            app.Szimulacio.Text = 'Szimuláció';
            app.Szimulacio.Value = true;
            app.Konvolucio = uiradiobutton(app.TpusButtonGroup);
            app.Konvolucio.Text = 'Konvolúció';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @Start, true);
            app.StartButton.Text = 'Start';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cauchy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end