classdef ito45 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        KepletEditField      matlab.ui.control.EditField
        KpletEditFieldLabel  matlab.ui.control.Label
        DifferenciaCheckBox  matlab.ui.control.CheckBox
        StartButton          matlab.ui.control.Button
        ItoPicture           matlab.ui.control.UIAxes
        TranszPicture        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartCallback(app, event)
            try
                if app.DifferenciaCheckBox.Value
                    n = 50000;
                else 
                    n = 1000;
                end
                m=100;
                try
                    f = str2sym(app.KepletEditField.Value);
                    g = diff(f);
                    g1 = g;
                    h = diff(g);
                    h1 = h;
                    f = matlabFunction(f);
                    g = matlabFunction(g);
                    h = matlabFunction(h);
                catch ME
                        msgbox(ME.messae);
                        return;
                end
                [w,t]= wiener(n,m);
                E = f(w)-f(0);
                plot(app.TranszPicture,t,E);
                grid(app.TranszPicture,'on');
                try 
                    X = g(w);
                catch
                    X = ones(size(w)).*double(g1);
                end
                I1 = stint(X,w);
                try
                    Y = h(w);
                catch
                    Y = ones(size(w)).*double(h1);
                end
                I2 = stint(Y,kvar(w));
                I = I1 + 0.5*I2;
                if app.DifferenciaCheckBox.Value
                    plot(app.ItoPicture,t,E-I);
                else
                    plot(app.ItoPicture,t,I);
                end
                grid(app.ItoPicture,'on');
            catch ME
                msgbox(ME.message)
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TranszPicture = uiaxes(app.UIFigure);
            title(app.TranszPicture, 'Transzformált')
            xlabel(app.TranszPicture, 'X')
            ylabel(app.TranszPicture, 'Y')
            zlabel(app.TranszPicture, 'Z')
            app.ItoPicture = uiaxes(app.UIFigure);
            title(app.ItoPicture, 'Ito felbontás')
            xlabel(app.ItoPicture, 'X')
            ylabel(app.ItoPicture, 'Y')
            zlabel(app.ItoPicture, 'Z')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartCallback, true);
            app.StartButton.Text = 'Start';
            app.DifferenciaCheckBox = uicheckbox(app.UIFigure);
            app.DifferenciaCheckBox.Text = 'Differencia';
            app.KpletEditFieldLabel = uilabel(app.UIFigure);
            app.KpletEditFieldLabel.Text = 'Képlet';
            app.KepletEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ito45

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end