classdef pltest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        IntegrandusEditField       matlab.ui.control.EditField
        IntegrandusEditFieldLabel  matlab.ui.control.Label
        DifferenciaCheckBox        matlab.ui.control.CheckBox
        StartButton                matlab.ui.control.Button
        KepletEditField            matlab.ui.control.EditField
        KpletEditFieldLabel        matlab.ui.control.Label
        Pl                         matlab.ui.control.UIAxes
        Kv                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function Start(app, event)
            n = 1000;
            m = 100;
            try
                f = str2sym(app.KepletEditField.Value);
                f = matlabFunction(f);
                I = str2sym(app.IntegrandusEditField.Value);
                I = matlabFunction(I);
            catch ME
                msgbox(ME.message);
                return;
            end
            [w,t] = wiener(n,m);
            II = stint(I(w),w);
            X = kvar(stint(f(w),w));
            plot(app.Kv,t,X);
            Y = stint(f(II).^2,kvar(II));
            if app.DifferenciaCheckBox.Value
            plot(app.Pl,t,X-Y)
            else
                plot(app.Pl,t,Y);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Kv = uiaxes(app.UIFigure);
            title(app.Kv, 'Kvadratikus variáció')
            xlabel(app.Kv, 'X')
            ylabel(app.Kv, 'Y')
            zlabel(app.Kv, 'Z')
            app.Pl = uiaxes(app.UIFigure);
            title(app.Pl, 'Polaritás')
            xlabel(app.Pl, 'X')
            ylabel(app.Pl, 'Y')
            zlabel(app.Pl, 'Z')
            app.KpletEditFieldLabel = uilabel(app.UIFigure);
            app.KpletEditFieldLabel.Text = 'Képlet';
            app.KepletEditField = uieditfield(app.UIFigure, 'text');
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @Start, true);
            app.StartButton.Text = 'Start';
            app.DifferenciaCheckBox = uicheckbox(app.UIFigure);
            app.DifferenciaCheckBox.Text = 'Differencia';
            app.IntegrandusEditFieldLabel = uilabel(app.UIFigure);
            app.IntegrandusEditFieldLabel.Text = 'Integrandus';
            app.IntegrandusEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pltest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end