classdef poiis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        GenerateButton  matlab.ui.control.Button
        Slider          matlab.ui.control.Slider
        SliderLabel     matlab.ui.control.Label
        Picture         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Process % folyamat amivel dolgozunk
        t; %idő
        IsProcess = false; %Még nincs folyamatunk
    end
    
    methods (Access = private)
        
        function PrintProcess(app)
            plot(app.Picture,app.t,app.Process);
            hold(app.Picture,"on");
            plot(app.Picture,app.t,ones(size(app.t))*a,'r','LineWidth',3);
            plot(app.Picture,app.t,zeros(size(app.t)),'g','LineWidth',3);
            plot(app.Picture,app.t,mean(app.Process,2),'b','LineWidth',3);
            hold(app.Picture,"off");
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateButton
        function Generate(app, event)
            app.IsProcess = true;
            n = 100;m= 1000;
            lambda = 10;
            T = 2;
            app.Process = zeros(size(app.t,1),m);
            app.t=0:T/n:T;
            for i = 1:m
                s = 0;
                while s<=T
                    s = s+exprnd(1/lambda);
                    if s<=T %garantálja, hogy ne adjon olyan koordinátát ami nem létezik.
                        app.Process(ceil((s/T)*(n+1)),i)=randn;
                    end
                end
            end
        PrintProcess(app.Process)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Picture = uiaxes(app.UIFigure);
            title(app.Picture, 'Összetett Poisson folyamat')
            xlabel(app.Picture, 'X')
            ylabel(app.Picture, 'Y')
            zlabel(app.Picture, 'Z')
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.GenerateButton = uibutton(app.UIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @Generate, true);
            app.GenerateButton.Text = 'Generate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = poiis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end